classdef speechtomorse < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        FREQUENCYSlider_2           matlab.ui.control.Slider
        FREQUENCYSlider_2Label      matlab.ui.control.Label
        REPLAYButton                matlab.ui.control.Button
        OUTPUTEditField             matlab.ui.control.EditField
        byVasileiosGavriilidisGeorgiosRossisLabel  matlab.ui.control.Label
        rectimesecondsSpinner       matlab.ui.control.Spinner
        rectimesecondsSpinnerLabel  matlab.ui.control.Label
        RECORDButton                matlab.ui.control.Button
        LANGUAGEButtonGroup         matlab.ui.container.ButtonGroup
        GREEKButton                 matlab.ui.control.RadioButton
        ENGLISHButton               matlab.ui.control.RadioButton
        SPEECHTOMORSECODELabel      matlab.ui.control.Label
        MODEButtonGroup             matlab.ui.container.ButtonGroup
        TELEGRAPHButton             matlab.ui.control.RadioButton
        RADIOButton                 matlab.ui.control.RadioButton
        VOLUMESliderLabel           matlab.ui.control.Label
        VOLUMESlider                matlab.ui.control.Slider
        CHARACTERSPEEDSliderLabel   matlab.ui.control.Label
        CHARACTERSPEEDSlider        matlab.ui.control.Slider
        WORDSPEEDSlider             matlab.ui.control.Slider
        WORDSPEEDSliderLabel        matlab.ui.control.Label
    end

    
    properties (Access = public)
        file
        whatlang
        usersaid
         
    end
    
    methods (Access = public)
         
        
        function tomorse = tomorse(app,usersaid)
            warning('off','all');
                        
            app.file = usersaid+"_to_morse_code.wav";
                        
            Fs= app.FREQUENCYSlider_2.Value; 
            Fs = uint64(Fs);
            Fs = num2str(Fs);
            Fs = str2num(Fs);
            
            
            letterv = app.WORDSPEEDSlider.Value; %space between letters
            spacev= app.CHARACTERSPEEDSlider.Value;%space between every char of one letter
            vol=app.VOLUMESlider.Value; % volume
            if app.RADIOButton.Value
                mode =1; %1=radio 2=telegraph
            else app.TELEGRAPHButton.Value
                mode =2;
            end
            
            if vol<0
                vol = vol * (-1);
            elseif vol>0
                vol = 1/vol;
            else vol==0;
                vol=1;
            end
            
            load wav; %load .mat files for the sound
            load typo;
            
            typo(:,2) = []; %make it mono channel
            
            if mode==1 %declare how long each sound lasts and what part of the mat file
                dot = wav(1130:2096);
                dash = wav(3201:6112);
                space = wav(6900:spacev);
                letter = wav(6900:letterv);
            elseif mode==2 
                dot = typo(326:4231);
                dash = typo(10276:18237);
                space = wav(6900:spacev);
                letter = wav(6900:letterv);
            end


            if app.whatlang==1 %english language
                A = [dot;space;dash];
                B = [dash;space;dot;space;dot;space;dot];
                C = [dash;space;dot;space;dash;space;dot];
                D = [dash;space;dot;space;dot];
                E = [dot];
                F = [dot;space;dot;space;dash;space;dot];
                G = [dash;space;dash;space;dot];
                H = [dot;space;dot;space;dot;space;dot];
                I = [dot;space;dot];
                J = [dot;space;dash;space;dash;space;dash];
                K = [dash;space;dot;space;dash];
                L = [dot;space;dash;space;dot;space;dot];
                M = [dash;space;dash];
                N = [dash;space;dot];
                O = [dash;space;dash;space;dash];
                P = [dot;space;dash;space;dash;space;dot];
                Q = [dash;space;dash;space;dot;space;dash];
                R = [dot;space;dash;space;dot];
                S = [dot;space;dot;space;dot];
                T = [dash];
                U = [dot;space;dot;space;dash];
                V = [dot;space;dot;space;dot;space;dash];
                W = [dot;space;dash;space;dash];
                X = [dash;space;dot;space;dot;space;dash];
                Y = [dash;space;dot;space;dash;space;dash];
                Z = [dash;space;dash;space;dot;space;dot];
            
                text = upper(app.usersaid);
            
                text= convertStringsToChars(text);
            
                morsecode=[];
            
                for i = 1:length(text)
            
                    if isvarname(text(i))
                        morsecode = [morsecode;eval(text(i))]; 
            
                    elseif text(i)==' '
                        morsecode = [morsecode;letter]; % after every word we add the "letter" space
                    end
            
                    morsecode = [morsecode;letter];
                end
            elseif app.whatlang==2 %greek alphabet
                text = upper(app.usersaid);
            
                text= convertStringsToChars(text);
            
                morsecode=[];
            
                for i = 1:length(text)
                    
                    if (text(i) == 'Α') || (text(i) == 'Ά')
                        morsecode = [morsecode;dot;space;dash];
            
                    elseif text(i) == 'Β'
                        morsecode = [morsecode;dash;space;dot;space;dot;space;dot];
            
                    elseif text(i) == 'Γ'
                        morsecode = [morsecode;dash;space;dash;space;dot];
            
                    elseif text(i) == 'Δ'
                        morsecode = [morsecode;dash;space;dot;space;dot];
            
                    elseif (text(i) == 'Ε') || (text(i) == 'Έ')
                        morsecode = [morsecode;dot];
            
                    elseif text(i) == 'Ζ'
                        morsecode = [morsecode;dash;space;dash;space;dot;space;dot];
            
                    elseif (text(i) == 'Η') || (text(i) == 'Ή')
                        morsecode = [morsecode;dot;space;dot;space;dot;space;dot];
            
                    elseif text(i) == 'Θ'
                        morsecode = [morsecode;dash;space;dot;space;dash;space;dot];
            
                    elseif (text(i) == 'Ι') || (text(i) == 'Ί')
                        morsecode = [morsecode;dot;space;dot];
            
                    elseif text(i) == 'Κ'
                        morsecode = [morsecode;dash;space;dot;space;dash];
            
                    elseif text(i) == 'Λ'
                        morsecode = [morsecode;dot;space;dash;space;dot;space;dot];
            
                    elseif text(i) == 'Μ'
                        morsecode = [morsecode;dash;space;dash];
            
                    elseif text(i) == 'Ν'
                        morsecode = [morsecode;dash;space;dot];
            
                    elseif text(i) == 'Ξ'
                        morsecode = [morsecode;dash;space;dot;space;dot;space;dash];
            
                    elseif (text(i) == 'Ο') || (text(i) == 'Ό')
                        morsecode = [morsecode;dash;space;dash;space;dash];
            
                    elseif text(i) == 'Π'
                        morsecode = [morsecode;dot;space;dash;space;dash;space;dot];
            
                    elseif text(i) == 'Ρ'
                        morsecode = [morsecode;dot;space;dash;space;dot];
            
                    elseif text(i) == 'Σ'
                        morsecode = [morsecode;dot;space;dot;space;dot];
            
                    elseif text(i) == 'Τ'
                        morsecode = [morsecode;dash];
            
                    elseif (text(i) == 'Υ') || (text(i) == 'Ύ')
                        morsecode = [morsecode;dash;space;dot;space;dash;space;dash];
            
                    elseif text(i) == 'Φ'
                        morsecode = [morsecode;dot;space;dot;space;dash;space;dot];
            
                    elseif text(i) == 'Χ'
                        morsecode = [morsecode;dash;space;dash;space;dash;space;dash]
            
                    elseif text(i) == 'Ψ'
                        morsecode = [morsecode;dash;space;dash;space;dot;space;dash];
            
                    elseif (text(i) == 'Ω') || (text(i) == 'Ώ')
                        morsecode = [morsecode;dot;space;dash;space;dash];
            
                    elseif text(i)==' ' %υπαρχει κενό
                        morsecode = [morsecode;letter]; % after every word we add the "letter" space
                    end
            
                    morsecode = [morsecode;letter]; % οπως και να εχει θα βάλουμε ένα κενό μετά απο κάθε γράμμα
                end
                
                
            end
            
            morsecode = morsecode/vol;
            sound(morsecode,Fs);
            audiowrite(app.file,morsecode, Fs);
            
            
        end
     
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RECORDButton
        function RECORDButtonPushed(app, event)
            Fsr=8000; %sample rate of recording
            ch=1; %channels (1=mono)
            nbits=16; %bits per sample
            Nseconds=app.rectimesecondsSpinner.Value; % duration of recording
            datatype='uint8';
            
            recorder=audiorecorder(Fsr,nbits,ch);

            app.OUTPUTEditField.Value = char(sprintf("Start speaking...\n"));
            
            recordblocking(recorder,Nseconds);
            
            app.OUTPUTEditField.Value = char(sprintf("End of Recording"));
            
            x=getaudiodata(recorder,datatype);
            
            audiowrite('speech.wav',x,Fsr); %save the recording
            
            
            if app.ENGLISHButton.Value
                app.whatlang=1;
                speechObject = speechClient('Google','languageCode','en-US'); % set the api client based on language
            else
                app.whatlang=2;
                speechObject = speechClient('Google','languageCode','el');
            end
            
            [y,fs] = audioread('speech.wav'); %read the recording
            
            try
                tableOut = speech2text(speechObject,y,fs,'HTTPTimeOut',35); %call the api
            catch %error handling if user doesnt say anything or api doesnt understand
            		app.OUTPUTEditField.Value = sprintf("Sorry, I did not catch that");
                    return
            end
            app.usersaid=tableOut.(1); %filter the returned value from the api to display only the text
            delete speech.wav;

            app.OUTPUTEditField.Value = char(sprintf("%s", app.usersaid));
                        
            app.usersaid = upper(app.usersaid);
                        
            app.usersaid= convertStringsToChars(app.usersaid);
                        
            tomorse(app,app.usersaid);
        end

        % Button pushed function: REPLAYButton
        function REPLAYButtonPushed(app, event)
            tomorse(app,app.usersaid);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.WORDSPEEDSliderLabel = uilabel(app.UIFigure);
            app.WORDSPEEDSliderLabel.Text = 'WORD SPEED';
            app.WORDSPEEDSlider = uislider(app.UIFigure);
            app.WORDSPEEDSlider.Limits = [7000 18000];
            app.WORDSPEEDSlider.MajorTicks = [7000 9750 12500 15250 18000];
            app.WORDSPEEDSlider.MajorTickLabels = {'FAST', '', '', '', 'SLOW'};
            app.WORDSPEEDSlider.Value = 10000;
            app.CHARACTERSPEEDSlider = uislider(app.UIFigure);
            app.CHARACTERSPEEDSlider.Limits = [7000 11000];
            app.CHARACTERSPEEDSlider.MajorTicks = [7000 8000 9000 10000 11000];
            app.CHARACTERSPEEDSlider.MajorTickLabels = {'FAST', '', '', '', 'SLOW'};
            app.CHARACTERSPEEDSlider.Value = 8100;
            app.CHARACTERSPEEDSliderLabel = uilabel(app.UIFigure);
            app.CHARACTERSPEEDSliderLabel.Text = 'CHARACTER SPEED';
            app.VOLUMESlider = uislider(app.UIFigure);
            app.VOLUMESlider.Limits = [-50 50];
            app.VOLUMESlider.MajorTicks = [-50 -25 0 25 50];
            app.VOLUMESlider.MajorTickLabels = {'LOW', '', '', '', 'HIGH'};
            app.VOLUMESlider.Value = 1;
            app.VOLUMESliderLabel = uilabel(app.UIFigure);
            app.VOLUMESliderLabel.Text = 'VOLUME';
            app.MODEButtonGroup = uibuttongroup(app.UIFigure);
            app.MODEButtonGroup.Title = 'MODE';
            app.RADIOButton = uiradiobutton(app.MODEButtonGroup);
            app.RADIOButton.Text = 'RADIO';
            app.RADIOButton.Value = true;
            app.TELEGRAPHButton = uiradiobutton(app.MODEButtonGroup);
            app.TELEGRAPHButton.Text = 'TELEGRAPH';
            app.SPEECHTOMORSECODELabel = uilabel(app.UIFigure);
            app.SPEECHTOMORSECODELabel.Text = 'SPEECH TO MORSE CODE';
            app.LANGUAGEButtonGroup = uibuttongroup(app.UIFigure);
            app.LANGUAGEButtonGroup.Title = 'LANGUAGE';
            app.ENGLISHButton = uiradiobutton(app.LANGUAGEButtonGroup);
            app.ENGLISHButton.Text = 'ENGLISH';
            app.ENGLISHButton.Value = true;
            app.GREEKButton = uiradiobutton(app.LANGUAGEButtonGroup);
            app.GREEKButton.Text = 'GREEK';
            app.RECORDButton = uibutton(app.UIFigure, 'push');
            app.RECORDButton.ButtonPushedFcn = createCallbackFcn(app, @RECORDButtonPushed, true);
            app.RECORDButton.Text = 'RECORD';
            app.rectimesecondsSpinnerLabel = uilabel(app.UIFigure);
            app.rectimesecondsSpinnerLabel.Text = 'rec time (seconds)';
            app.rectimesecondsSpinner = uispinner(app.UIFigure);
            app.rectimesecondsSpinner.Limits = [2 8];
            app.rectimesecondsSpinner.Value = 2;
            app.byVasileiosGavriilidisGeorgiosRossisLabel = uilabel(app.UIFigure);
            app.byVasileiosGavriilidisGeorgiosRossisLabel.Text = {'by'; 'Vasileios Gavriilidis'; 'Georgios Rossis'};
            app.OUTPUTEditField = uieditfield(app.UIFigure, 'text');
            app.OUTPUTEditField.Editable = 'off';
            app.REPLAYButton = uibutton(app.UIFigure, 'push');
            app.REPLAYButton.ButtonPushedFcn = createCallbackFcn(app, @REPLAYButtonPushed, true);
            app.REPLAYButton.Text = 'REPLAY';
            app.FREQUENCYSlider_2Label = uilabel(app.UIFigure);
            app.FREQUENCYSlider_2Label.Text = 'FREQUENCY';
            app.FREQUENCYSlider_2 = uislider(app.UIFigure);
            app.FREQUENCYSlider_2.Limits = [6000 22000];
            app.FREQUENCYSlider_2.MajorTicks = [6000 14000 22000];
            app.FREQUENCYSlider_2.MajorTickLabels = {'LOW', '', 'HIGH'};
            app.FREQUENCYSlider_2.MinorTicks = [6000 6200 6400 6600 6800 7000 7200 7400 7600 7800 8000 8200 8400 8600 8800 9000 9200 9400 9600 9800 10000 10200 10400 10600 10800 11000 11200 11400 11600 11800 12000 12200 12400 12600 12800 13000 13200 13400 13600 13800 14000 14200 14400 14600 14800 15000 15200 15400 15600 15800 16000 16200 16400 16600 16800 17000 17200 17400 17600 17800 18000 18200 18400 18600 18800 19000 19200 19400 19600 19800 20000 20200 20400 20600 20800 21000 21200 21400 21600 21800 22000];
            app.FREQUENCYSlider_2.Value = 10000;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = speechtomorse

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end